classdef evalkit_app_forBLE < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        GridLayout7                     matlab.ui.container.GridLayout
        FilterButtonGroup               matlab.ui.container.ButtonGroup
        MahonyButton                    matlab.ui.control.RadioButton
        MadgwickButton                  matlab.ui.control.RadioButton
        ConnectButton                   matlab.ui.control.Button
        GridLayout8                     matlab.ui.container.GridLayout
        PortEditField                   matlab.ui.control.EditField
        PortEditFieldLabel              matlab.ui.control.Label
        SampleDataEditField             matlab.ui.control.NumericEditField
        SampleDataEditFieldLabel        matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        GyroAccelTab                    matlab.ui.container.Tab
        GridLayout2                     matlab.ui.container.GridLayout
        UIAxes7                         matlab.ui.control.UIAxes
        UIAxes6                         matlab.ui.control.UIAxes
        TiltcompensatedaccelerometerTab  matlab.ui.container.Tab
        GridLayout3                     matlab.ui.container.GridLayout
        UIAxes9                         matlab.ui.control.UIAxes
        UIAxes8                         matlab.ui.control.UIAxes
        LinearVelocityTab               matlab.ui.container.Tab
        GridLayout4                     matlab.ui.container.GridLayout
        UIAxes11                        matlab.ui.control.UIAxes
        UIAxes10                        matlab.ui.control.UIAxes
        PositionTab                     matlab.ui.container.Tab
        GridLayout5                     matlab.ui.container.GridLayout
        UIAxes13                        matlab.ui.control.UIAxes
        UIAxes12                        matlab.ui.control.UIAxes
        DAnimationTab                   matlab.ui.container.Tab
        GridLayout6                     matlab.ui.container.GridLayout
        UIAxes14                        matlab.ui.control.UIAxes
        DOFButtonGroup                  matlab.ui.container.ButtonGroup
        GyroAccButton                   matlab.ui.control.RadioButton
        GyroAccMagButton_2              matlab.ui.control.RadioButton
        MagnetometerCalibrationButton   matlab.ui.control.Button
        newdataButton                   matlab.ui.control.Button
        RUNButton                       matlab.ui.control.Button
        ParameterPanel                  matlab.ui.container.Panel
        GridLayout                      matlab.ui.container.GridLayout
        MadgwickSpinner                 matlab.ui.control.Spinner
        MadgwickSpinnerLabel            matlab.ui.control.Label
        CutoffFrequencyPositionSpinner  matlab.ui.control.Spinner
        CutoffFrequencyPositionSpinnerLabel  matlab.ui.control.Label
        CutoffFrequencyLinearVelocitySpinner  matlab.ui.control.Spinner
        CutoffFrequencyLinearVelocitySpinnerLabel  matlab.ui.control.Label
        KiMahonySpinner                 matlab.ui.control.Spinner
        KiLabel                         matlab.ui.control.Label
        KpMahonySpinner                 matlab.ui.control.Spinner
        KpLabel                         matlab.ui.control.Label
    end

    
    properties (Access = private)
        SerialPort="COM9" % Serial Port
        device % IMU Device
        % Mahony filter gain
        Kp = 1;
        Ki = 0;

        Beta = 0.1; % Madgwick filter gain

        cutoff_linvel = 0.1; %cutoff for high-pass filter (linear velocity)
        cutoff_position = 0.1 %cutoff for high-pass filter (position) 
        NumOfSample = 500;
        samplePeriod = 14; 
        DOF = "9DOF" % "9DOF" of "6DOF"

        %for magnetometor calibration
        magx_correction = 0;
        magy_correction = 0;
        magz_correction = 0;

        %read from imu
        gyr;
        acc;
        mag;

        Filter = "Madgwick"; % Select Filter, "Madgwick" or "Mahony"
        
    end
    
    methods (Access = private)
        
    function fig = SixDOFanimation(app, varargin)

    %% Create local variables

    % Required arguments
    p = varargin{1};                % position of body
    R = varargin{2};                % rotation matrix of body
    [numSamples dummy] = size(p);

    % Default values of optional arguments
    SamplePlotFreq = 1;
    Trail = 'Off';
    LimitRatio = 1;
    Position = [];
    FullScreen = false;
    View = [30 20];
    AxisLength = 1;
    ShowArrowHead = 'on';
    Xlabel = 'X';
    Ylabel = 'Y';
    Zlabel = 'Z';
    Title = '6DOF Animation';
    ShowLegend = true;
    CreateAVI = false;
    AVIfileName = '6DOF Animation';
    AVIfileNameEnum = true;
    AVIfps = 30;

    for i = 3:2:nargin-1
        if  strcmp(varargin{i}, 'SamplePlotFreq'), SamplePlotFreq = varargin{i+1};
        elseif  strcmp(varargin{i}, 'Trail')
            Trail = varargin{i+1};
            if(~strcmp(Trail, 'Off') && ~strcmp(Trail, 'DotsOnly') && ~strcmp(Trail, 'All'))
                error('Invalid argument.  Trail must be ''Off'', ''DotsOnly'' or ''All''.');
            end
        elseif  strcmp(varargin{i}, 'LimitRatio'), LimitRatio = varargin{i+1};
        elseif  strcmp(varargin{i}, 'Position'), Position = varargin{i+1};
        elseif  strcmp(varargin{i}, 'FullScreen'), FullScreen = varargin{i+1};
        elseif  strcmp(varargin{i}, 'View'), View = varargin{i+1};
        elseif  strcmp(varargin{i}, 'AxisLength'), AxisLength = varargin{i+1};
        elseif  strcmp(varargin{i}, 'ShowArrowHead'), ShowArrowHead = varargin{i+1};
        elseif  strcmp(varargin{i}, 'Xlabel'), Xlabel = varargin{i+1};
        elseif  strcmp(varargin{i}, 'Ylabel'), Ylabel = varargin{i+1};
        elseif  strcmp(varargin{i}, 'Zlabel'), Zlabel = varargin{i+1};
        elseif  strcmp(varargin{i}, 'Title'), Title = varargin{i+1};
        elseif  strcmp(varargin{i}, 'ShowLegend'), ShowLegend = varargin{i+1};
        elseif  strcmp(varargin{i}, 'CreateAVI'), CreateAVI = varargin{i+1};
        elseif  strcmp(varargin{i}, 'AVIfileName'), AVIfileName = varargin{i+1};
        elseif  strcmp(varargin{i}, 'AVIfileNameEnum'), AVIfileNameEnum = varargin{i+1};
        elseif  strcmp(varargin{i}, 'AVIfps'), AVIfps = varargin{i+1};
        else error('Invalid argument.');
        end
    end;

    %% Reduce data to samples to plot only

    p = p(1:SamplePlotFreq:numSamples, :);
    R = R(:, :, 1:SamplePlotFreq:numSamples) * AxisLength;
    if(numel(View) > 2)
        View = View(1:SamplePlotFreq:numSamples, :);
    end
    [numPlotSamples dummy] = size(p);

    %% Setup AVI file

    aviobj = [];                                                            	% create null object
    if(CreateAVI)
        fileName = strcat(AVIfileName, '.avi');
        if(exist(fileName, 'file'))
            if(AVIfileNameEnum)                                              	% if file name exists and enum enabled
                i = 0;
                while(exist(fileName, 'file'))                                  % find un-used file name by appending enum
                    fileName = strcat(AVIfileName, sprintf('%i', i), '.avi');
                    i = i + 1;
                end
            else                                                                % else file name exists and enum disabled
                fileName = [];                                                  % file will not be created
            end
        end
        if(isempty(fileName))
            sprintf('AVI file not created as file already exists.')
        else
            aviobj = avifile(fileName, 'fps', AVIfps, 'compression', 'Cinepak', 'quality', 100);
        end
    end

    %% Setup figure and plot

    % Create figure
    fig = figure('NumberTitle', 'off', 'Name', '6DOF Animation');
    if(FullScreen)
        screenSize = get(0, 'ScreenSize');
        set(fig, 'Position', [0 0 screenSize(3) screenSize(4)]);
    elseif(~isempty(Position))
        set(fig, 'Position', Position);
    end
    set(gca, 'drawmode', 'fast');
    lighting phong;
    set(gcf, 'Renderer', 'zbuffer');
    hold on;
    axis equal;
    grid on;
    view(View(1, 1), View(1, 2));
    title(i);
    xlabel(Xlabel);
    ylabel(Ylabel);
    zlabel(Zlabel);

    % Create plot data arrays
    if(strcmp(Trail, 'DotsOnly') || strcmp(Trail, 'All'))
        x = zeros(numPlotSamples, 1);
        y = zeros(numPlotSamples, 1);
        z = zeros(numPlotSamples, 1);
    end
    if(strcmp(Trail, 'All'))
        ox = zeros(numPlotSamples, 1);
        oy = zeros(numPlotSamples, 1);
        oz = zeros(numPlotSamples, 1);
        ux = zeros(numPlotSamples, 1);
        vx = zeros(numPlotSamples, 1);
        wx = zeros(numPlotSamples, 1);
        uy = zeros(numPlotSamples, 1);
        vy = zeros(numPlotSamples, 1);
        wy = zeros(numPlotSamples, 1);
        uz = zeros(numPlotSamples, 1);
        vz = zeros(numPlotSamples, 1);
        wz = zeros(numPlotSamples, 1);
    end
    x(1) = p(1,1);
    y(1) = p(1,2);
    z(1) = p(1,3);
    ox(1) = x(1);
    oy(1) = y(1);
    oz(1) = z(1);
    ux(1) = R(1,1,1:1);
    vx(1) = R(2,1,1:1);
    wx(1) = R(3,1,1:1);
    uy(1) = R(1,2,1:1);
    vy(1) = R(2,2,1:1);
    wy(1) = R(3,2,1:1);
    uz(1) = R(1,3,1:1);
    vz(1) = R(2,3,1:1);
    wz(1) = R(3,3,1:1);

    % Create graphics handles
    orgHandle = plot3(x, y, z, 'k-');
    if(ShowArrowHead)
        ShowArrowHeadStr = 'on';
    else
        ShowArrowHeadStr = 'off';
    end
    quivXhandle = quiver3(ox, oy, oz, ux, vx, wx,  'r', 'ShowArrowHead', ShowArrowHeadStr, 'MaxHeadSize', 0.999999, 'AutoScale', 'off');
    quivYhandle = quiver3(ox, oy, oz, uy, vy, wy,  'g', 'ShowArrowHead', ShowArrowHeadStr, 'MaxHeadSize', 0.999999, 'AutoScale', 'off');
    quivZhandle = quiver3(ox, ox, oz, uz, vz, wz,  'b', 'ShowArrowHead', ShowArrowHeadStr, 'MaxHeadSize', 0.999999, 'AutoScale', 'off');

    % Create legend
    if(ShowLegend)
        legend('Origin', 'X', 'Y', 'Z');
    end
    
    % Set initial limits
    Xlim = [x(1)-AxisLength x(1)+AxisLength] * LimitRatio;
    Ylim = [y(1)-AxisLength y(1)+AxisLength] * LimitRatio;
    Zlim = [z(1)-AxisLength z(1)+AxisLength] * LimitRatio;
    set(gca, 'Xlim', Xlim, 'Ylim', Ylim, 'Zlim', Zlim);
    
    % Set initial view
    view(View(1, :));

    %% Plot one sample at a time

    for i = 1:numPlotSamples

        % Update graph title
        if(strcmp(Title, ''))
            titleText = sprintf('Sample %i of %i', 1+((i-1)*SamplePlotFreq), numSamples);
        else
            titleText = strcat(Title, ' (', sprintf('Sample %i of %i', 1+((i-1)*SamplePlotFreq), numSamples), ')');
        end
        title(titleText);

        % Plot body x y z axes
        if(strcmp(Trail, 'DotsOnly') || strcmp(Trail, 'All'))
            x(1:i) = p(1:i,1);
            y(1:i) = p(1:i,2);
            z(1:i) = p(1:i,3);
        else
            x = p(i,1);
            y = p(i,2);
            z = p(i,3);
        end
        if(strcmp(Trail, 'All'))
            ox(1:i) = p(1:i,1);
            oy(1:i) = p(1:i,2);
            oz(1:i) = p(1:i,3);
            ux(1:i) = R(1,1,1:i);
            vx(1:i) = R(2,1,1:i);
            wx(1:i) = R(3,1,1:i);
            uy(1:i) = R(1,2,1:i);
            vy(1:i) = R(2,2,1:i);
            wy(1:i) = R(3,2,1:i);
            uz(1:i) = R(1,3,1:i);
            vz(1:i) = R(2,3,1:i);
            wz(1:i) = R(3,3,1:i);
        else
            ox = p(i,1);
            oy = p(i,2);
            oz = p(i,3);
            ux = R(1,1,i);
            vx = R(2,1,i);
            wx = R(3,1,i);
            uy = R(1,2,i);
            vy = R(2,2,i);
            wy = R(3,2,i);
            uz = R(1,3,i);
            vz = R(2,3,i);
            wz = R(3,3,i);
        end
        set(orgHandle, 'xdata', x, 'ydata', y, 'zdata', z);
        set(quivXhandle, 'xdata', ox, 'ydata', oy, 'zdata', oz,'udata', ux, 'vdata', vx, 'wdata', wx);
        set(quivYhandle, 'xdata', ox, 'ydata', oy, 'zdata', oz,'udata', uy, 'vdata', vy, 'wdata', wy);
        set(quivZhandle, 'xdata', ox, 'ydata', oy, 'zdata', oz,'udata', uz, 'vdata', vz, 'wdata', wz);

        % Adjust axes for snug fit and draw
        axisLimChanged = false;
        if((p(i,1) - AxisLength) < Xlim(1)), Xlim(1) = p(i,1) - LimitRatio*AxisLength; axisLimChanged = true; end
        if((p(i,2) - AxisLength) < Ylim(1)), Ylim(1) = p(i,2) - LimitRatio*AxisLength; axisLimChanged = true; end
        if((p(i,3) - AxisLength) < Zlim(1)), Zlim(1) = p(i,3) - LimitRatio*AxisLength; axisLimChanged = true; end
        if((p(i,1) + AxisLength) > Xlim(2)), Xlim(2) = p(i,1) + LimitRatio*AxisLength; axisLimChanged = true; end
        if((p(i,2) + AxisLength) > Ylim(2)), Ylim(2) = p(i,2) + LimitRatio*AxisLength; axisLimChanged = true; end
        if((p(i,3) + AxisLength) > Zlim(2)), Zlim(2) = p(i,3) + LimitRatio*AxisLength; axisLimChanged = true; end
        if(axisLimChanged), set(gca, 'Xlim', Xlim, 'Ylim', Ylim, 'Zlim', Zlim); end
        drawnow;

        % Adjust view
        if(numel(View) > 2)
            view(View(i, :));
        end

        % Add frame to AVI object
        if(~isempty(aviobj))
            frame = getframe(fig);
            aviobj = addframe(aviobj, frame);
        end

    end

    hold off;

    % Close AVI file
    if(~isempty(aviobj))
        aviobj = close(aviobj);
    end

end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            addpath('ximu_matlab_library');	% include x-IMU MATLAB library
            addpath('quaternion_library');	% include quatenrion library
            close all;                     	% close all figures
            clear;                         	% clear all variables
            clc;                          	% clear the command terminal
        end

        % Value changed function: PortEditField
        function PortEditFieldValueChanged(app, event)
            value = app.PortEditField.Value;
            app.SerialPort = value; %set serial port
        end

        % Button pushed function: ConnectButton
        function ConnectSerial(app, event)
            app.device = serialport(app.SerialPort,9600);
        end

        % Value changed function: KpMahonySpinner
        function SetKp(app, event)
            value = app.KpMahonySpinner.Value;
            app.Kp = value;
        end

        % Value changed function: KiMahonySpinner
        function SetKi(app, event)
            value = app.KiMahonySpinner.Value;
            app.Ki = value;
        end

        % Value changed function: CutoffFrequencyPositionSpinner
        function SetCutoff_pos(app, event)
            value = app.CutoffFrequencyLinearVelocitySpinner.Value;
            app.cutoff_linvel = value;
        end

        % Value changed function: CutoffFrequencyLinearVelocitySpinner
        function CutoffFrequencyLinearVelocitySpinnerValueChanged(app, event)
            value = app.CutoffFrequencyLinearVelocitySpinner.Value;
            app.cutoff_position = value;
        end

        % Button pushed function: MagnetometerCalibrationButton
        function MagnetometerCalibrationButtonPushed(app, event)
            sampleMData = 1000;
            fig = waitbar(0, "Rotate each axis", 'Name', "Calibrating Magnetometer...");
            flush(app.device);
            readline(app.device);

            tic;
            stopTimer = 10;
            magReadings=[];

            while(toc<stopTimer)
                imudata = cast(split(readline(app.device), ", "),"double");
                mag = [imudata(7) imudata(8) imudata(9)];
                magReadings = [magReadings;mag];
                waitbar(toc/stopTimer,fig)
            end
            delete(fig);
                
            magx_min = min(magReadings(:,1));
            magx_max = max(magReadings(:,1));
            magy_min = min(magReadings(:,2));
            magy_max = max(magReadings(:,2));
            magz_min = min(magReadings(:,3));
            magz_max = max(magReadings(:,3));


            app.magx_correction = (magx_max+magx_min)/2;
            app.magy_correction = (magy_max+magy_min)/2;
            app.magz_correction = (magz_max+magz_min)/2;
        end

        % Button pushed function: newdataButton
        function newdataButtonPushed(app, event)
            %refresh data
            app.gyr = zeros(app.NumOfSample, 3);
            app.acc = zeros(app.NumOfSample, 3);
            app.mag = zeros(app.NumOfSample, 3);

            msgfig = msgbox("Now Start sampling",'modal');
            uiwait(msgfig);
            
            clearvars imudata;
            imudata = zeros([app.NumOfSample 9]);
            fig = waitbar(0, "Move imu", 'Name', "Read data from imu");
            flush(app.device);
            readline(app.device);
            tic
            for c = 1:app.NumOfSample
                waitbar(c/app.NumOfSample,fig)
                imudata(c,:) = cast(split(readline(app.device), ", "),"double");
            end
            timeInt = toc;
            delete(fig);
            
            sampleFrequency = app.NumOfSample / timeInt;
            app.samplePeriod = 1/sampleFrequency;
            
            app.gyr = [imudata(:, 1) imudata(:, 2) imudata(:, 3)];
            app.acc = [imudata(:, 4) imudata(:, 5) imudata(:, 6)];
            app.mag = [imudata(:, 7)-app.magx_correction imudata(:, 8)-app.magy_correction imudata(:, 9)-app.magz_correction];
        end

        % Value changed function: SampleDataEditField
        function SampleDataEditFieldValueChanged(app, event)
            value = app.SampleDataEditField.Value;
            app.NumOfSample = value;
        end

        % Selection changed function: DOFButtonGroup
        function DOFButtonGroupSelectionChanged(app, event)
            selectedButton = app.DOFButtonGroup.SelectedObject;
            
            if selectedButton == "Gyro, Acc, Mag"
                app.DOF = "9DOF";
            else
                app.DOF = "6DOF";
            end
            
        end

        % Button pushed function: RUNButton
        function RUNButtonPushed(app, event)
            %% Process data through AHRS algorithm (calcualte orientation)
            % See: http://www.x-io.co.uk/open-source-imu-and-ahrs-algorithms/
            
            R = zeros(3,3,length(app.gyr));     % rotation matrix describing sensor relative to Earth
            
            if app.Filter == "Madgwick"
                ahrs = MadgwickAHRS('SamplePeriod', app.samplePeriod, 'Beta', app.Beta);
            else
                ahrs = MahonyAHRS('SamplePeriod', app.samplePeriod, 'Kp', app.Kp, 'Ki', app.Ki);
            end
            %KP값을 조절하여 자이로값과 자기센서값의 가중치를 선택가능
            for i = 1:length(app.gyr)
                if(app.DOF == "9DOF")
                    ahrs.Update(app.gyr(i,:) * (pi/180), app.acc(i,:), app.mag(i, :)); % 자기센서 O
                else
                    ahrs.UpdateIMU(app.gyr(i,:) * (pi/180), app.acc(i,:));	% 자기센서 X
                end
                
                R(:,:,i) = quatern2rotMat(ahrs.Quaternion)';    % transpose because ahrs provides Earth relative to sensor
            end
            
            %% Calculate 'tilt-compensated' accelerometer
            
            tcAcc = zeros(size(app.acc));  % accelerometer in Earth frame
            
            for i = 1:length(app.acc)
            tcAcc(i,:) = R(:,:,i) * app.acc(i,:)';
            end
            
            
            %% Calculate linear acceleration in Earth frame (subtracting gravity)
            
            linAcc = tcAcc - [zeros(length(tcAcc), 1), zeros(length(tcAcc), 1), ones(length(tcAcc), 1)];
            linAcc = linAcc * 9.81;     % convert from 'g' to m/s/s
            
            
            %% Calculate linear velocity (integrate acceleartion)
            
            linVel = zeros(size(linAcc));
            
            for i = 2:length(linAcc)
            linVel(i,:) = linVel(i-1,:) + linAcc(i,:) * app.samplePeriod;
            end
            
            
            %% High-pass filter linear velocity to remove drift
            
            order = 1;
            filtCutOff = app.cutoff_linvel;
            % 0 < (2*filtCutOff)/(1/samplePeriod) < 1
            [b, a] = butter(order, (2*filtCutOff)/(1/app.samplePeriod), 'high');
            linVelHP = filtfilt(b, a, linVel);
            
            
            %% Calculate linear position (integrate velocity)
            
            linPos = zeros(size(linVelHP));
            
            for i = 2:length(linVelHP)
            linPos(i,:) = linPos(i-1,:) + linVelHP(i,:) * app.samplePeriod;
            end
            
            
            %% High-pass filter linear position to remove drift
            
            order = 1;
            filtCutOff = app.cutoff_position;
            % 0 < (2*filtCutOff)/(1/samplePeriod) < 1 
            [b, a] = butter(order, (2*filtCutOff)/(1/app.samplePeriod), 'high');
            linPosHP = filtfilt(b, a, linPos);

            %% plot
            cla(app.UIAxes6);
            cla(app.UIAxes7);
            cla(app.UIAxes8);
            cla(app.UIAxes9);
            cla(app.UIAxes10);
            cla(app.UIAxes11);
            cla(app.UIAxes12);
            cla(app.UIAxes13);

            hold(app.UIAxes6, "on");
            plot(app.UIAxes6, app.gyr(:,1), 'r');
            plot(app.UIAxes6, app.gyr(:,2), 'g');
            plot(app.UIAxes6, app.gyr(:,3), 'b');
            xlabel(app.UIAxes6,'sample');
            ylabel(app.UIAxes6,'dps');
            title(app.UIAxes6, 'Gyroscope');
            legend(app.UIAxes6, 'X', 'Y', 'Z');
            hold(app.UIAxes6, "off")

            hold(app.UIAxes7, "on");
            plot(app.UIAxes7, app.acc(:,1), 'r');
            plot(app.UIAxes7, app.acc(:,2), 'g');
            plot(app.UIAxes7, app.acc(:,3), 'b');
            xlabel(app.UIAxes7, 'sample');
            ylabel(app.UIAxes7, 'g');
            title(app.UIAxes7, 'Accelerometer');
            legend(app.UIAxes7, 'X', 'Y', 'Z');
            hold(app.UIAxes7, "off");

            hold(app.UIAxes8, "on");
            plot(app.UIAxes8, tcAcc(:,1), 'r');
            plot(app.UIAxes8, tcAcc(:,2), 'g');
            plot(app.UIAxes8, tcAcc(:,3), 'b');
            xlabel(app.UIAxes8, 'sample');
            ylabel(app.UIAxes8, 'g');
            title(app.UIAxes8, '''Tilt-compensated'' accelerometer');
            legend(app.UIAxes8, 'X', 'Y', 'Z');
            hold(app.UIAxes8, "off");

            hold(app.UIAxes9, "on");
            plot(app.UIAxes9, linAcc(:,1), 'r');
            plot(app.UIAxes9, linAcc(:,2), 'g');
            plot(app.UIAxes9, linAcc(:,3), 'b');
            xlabel(app.UIAxes9, 'sample');
            ylabel(app.UIAxes9, 'g');
            title(app.UIAxes9, 'Linear acceleration');
            legend(app.UIAxes9, 'X', 'Y', 'Z');
            hold(app.UIAxes9, "off");

            hold(app.UIAxes10, "on");
            plot(app.UIAxes10, linVel(:,1), 'r');
            plot(app.UIAxes10, linVel(:,2), 'g');
            plot(app.UIAxes10, linVel(:,3), 'b');
            xlabel(app.UIAxes10, 'sample');
            ylabel(app.UIAxes10, 'g');
            title(app.UIAxes10, 'Linear velocity');
            legend(app.UIAxes10, 'X', 'Y', 'Z');
            hold(app.UIAxes10, "off");

            hold(app.UIAxes11, "on");
            plot(app.UIAxes11, linVelHP(:,1), 'r');
            plot(app.UIAxes11, linVelHP(:,2), 'g');
            plot(app.UIAxes11, linVelHP(:,3), 'b');
            xlabel(app.UIAxes11, 'sample');
            ylabel(app.UIAxes11, 'g');
            title(app.UIAxes11, 'High-pass filtered linear velocity');
            legend(app.UIAxes11, 'X', 'Y', 'Z');
            hold(app.UIAxes11, "off");

            hold(app.UIAxes12, "on");
            plot(app.UIAxes12, linPos(:,1), 'r');
            plot(app.UIAxes12, linPos(:,2), 'g');
            plot(app.UIAxes12, linPos(:,3), 'b');
            xlabel(app.UIAxes12, 'sample');
            ylabel(app.UIAxes12, 'g');
            title(app.UIAxes12, 'Linear position');
            legend(app.UIAxes12, 'X', 'Y', 'Z');
            hold(app.UIAxes12, "on");

            hold(app.UIAxes13, "on");
            plot(app.UIAxes13, linPosHP(:,1), 'r');
            plot(app.UIAxes13, linPosHP(:,2), 'g');
            plot(app.UIAxes13, linPosHP(:,3), 'b');
            xlabel(app.UIAxes13, 'sample');
            ylabel(app.UIAxes13, 'g');
            title(app.UIAxes13, 'High-pass filtered linear position');
            legend(app.UIAxes13, 'X', 'Y', 'Z');
            hold(app.UIAxes13, "off");

            SamplePlotFreq = 2;

            SixDOFanimation(app, linPosHP, R, ...
                            'SamplePlotFreq', SamplePlotFreq, 'Trail', 'DotsOnly', ...
                            'Position', [9 39 1280 720], ...
                            'AxisLength', 0.1, 'ShowArrowHead', false, ...
                            'Xlabel', 'X (m)', 'Ylabel', 'Y (m)', 'Zlabel', 'Z (m)', 'ShowLegend', false, 'Title', 'Unfiltered',...
                            'CreateAVI', false, 'AVIfileNameEnum', false, 'AVIfps', ((1/app.samplePeriod) / SamplePlotFreq));  
        end

        % Selection changed function: FilterButtonGroup
        function FilterButtonGroupSelectionChanged(app, event)
            selectedButton = app.FilterButtonGroup.SelectedObject;
            
            app.Filter = selectedButton;
        end

        % Value changed function: MadgwickSpinner
        function MadgwickSpinnerValueChanged(app, event)
            value = app.MadgwickSpinner.Value;
            
            app.Beta = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout7 = uigridlayout(app.UIFigure);
            app.ParameterPanel = uipanel(app.GridLayout7);
            app.ParameterPanel.Title = 'Parameter';
            app.ParameterPanel.Tag = 'SliderPanel';
            app.GridLayout = uigridlayout(app.ParameterPanel);
            app.KpLabel = uilabel(app.GridLayout);
            app.KpLabel.Text = 'Kp (Mahony)';
            app.KpMahonySpinner = uispinner(app.GridLayout);
            app.KpMahonySpinner.Step = 0.1;
            app.KpMahonySpinner.Limits = [0 Inf];
            app.KpMahonySpinner.ValueChangedFcn = createCallbackFcn(app, @SetKp, true);
            app.KpMahonySpinner.Value = 1;
            app.KiLabel = uilabel(app.GridLayout);
            app.KiLabel.Text = 'Ki (Mahony)';
            app.KiMahonySpinner = uispinner(app.GridLayout);
            app.KiMahonySpinner.Step = 0.1;
            app.KiMahonySpinner.Limits = [0 Inf];
            app.KiMahonySpinner.ValueChangedFcn = createCallbackFcn(app, @SetKi, true);
            app.CutoffFrequencyLinearVelocitySpinnerLabel = uilabel(app.GridLayout);
            app.CutoffFrequencyLinearVelocitySpinnerLabel.Text = {'Cutoff Frequency'; '(Linear Velocity)'};
            app.CutoffFrequencyLinearVelocitySpinner = uispinner(app.GridLayout);
            app.CutoffFrequencyLinearVelocitySpinner.Step = 0.01;
            app.CutoffFrequencyLinearVelocitySpinner.ValueChangedFcn = createCallbackFcn(app, @CutoffFrequencyLinearVelocitySpinnerValueChanged, true);
            app.CutoffFrequencyLinearVelocitySpinner.Value = 0.1;
            app.CutoffFrequencyPositionSpinnerLabel = uilabel(app.GridLayout);
            app.CutoffFrequencyPositionSpinnerLabel.Text = {'Cutoff Frequency'; '(Position)'};
            app.CutoffFrequencyPositionSpinner = uispinner(app.GridLayout);
            app.CutoffFrequencyPositionSpinner.Step = 0.01;
            app.CutoffFrequencyPositionSpinner.ValueChangedFcn = createCallbackFcn(app, @SetCutoff_pos, true);
            app.CutoffFrequencyPositionSpinner.Value = 0.1;
            app.MadgwickSpinnerLabel = uilabel(app.GridLayout);
            app.MadgwickSpinnerLabel.Text = 'β (Madgwick)';
            app.MadgwickSpinner = uispinner(app.GridLayout);
            app.MadgwickSpinner.Step = 0.01;
            app.MadgwickSpinner.ValueChangedFcn = createCallbackFcn(app, @MadgwickSpinnerValueChanged, true);
            app.MadgwickSpinner.Value = 0.1;
            app.RUNButton = uibutton(app.GridLayout7, 'push');
            app.RUNButton.ButtonPushedFcn = createCallbackFcn(app, @RUNButtonPushed, true);
            app.RUNButton.Text = 'RUN';
            app.newdataButton = uibutton(app.GridLayout7, 'push');
            app.newdataButton.ButtonPushedFcn = createCallbackFcn(app, @newdataButtonPushed, true);
            app.newdataButton.Text = 'new data';
            app.MagnetometerCalibrationButton = uibutton(app.GridLayout7, 'push');
            app.MagnetometerCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @MagnetometerCalibrationButtonPushed, true);
            app.MagnetometerCalibrationButton.Text = {'Magnetometer'; 'Calibration'};
            app.DOFButtonGroup = uibuttongroup(app.GridLayout7);
            app.DOFButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @DOFButtonGroupSelectionChanged, true);
            app.DOFButtonGroup.Title = 'DOF';
            app.GyroAccMagButton_2 = uiradiobutton(app.DOFButtonGroup);
            app.GyroAccMagButton_2.Text = 'Gyro, Acc, Mag';
            app.GyroAccMagButton_2.Value = true;
            app.GyroAccButton = uiradiobutton(app.DOFButtonGroup);
            app.GyroAccButton.Text = 'Gyro, Acc';
            app.TabGroup = uitabgroup(app.GridLayout7);
            app.GyroAccelTab = uitab(app.TabGroup);
            app.GyroAccelTab.Title = 'Gyro, Accel';
            app.GridLayout2 = uigridlayout(app.GyroAccelTab);
            app.UIAxes6 = uiaxes(app.GridLayout2);
            title(app.UIAxes6, 'Gyroscope')
            xlabel(app.UIAxes6, 'X')
            ylabel(app.UIAxes6, 'Y')
            zlabel(app.UIAxes6, 'Z')
            app.UIAxes7 = uiaxes(app.GridLayout2);
            title(app.UIAxes7, 'Accelerometer')
            xlabel(app.UIAxes7, 'X')
            ylabel(app.UIAxes7, 'Y')
            zlabel(app.UIAxes7, 'Z')
            app.TiltcompensatedaccelerometerTab = uitab(app.TabGroup);
            app.TiltcompensatedaccelerometerTab.Title = 'Tilt-compensated, accelerometer';
            app.GridLayout3 = uigridlayout(app.TiltcompensatedaccelerometerTab);
            app.UIAxes8 = uiaxes(app.GridLayout3);
            title(app.UIAxes8, 'Tilt-compensated')
            xlabel(app.UIAxes8, 'X')
            ylabel(app.UIAxes8, 'Y')
            zlabel(app.UIAxes8, 'Z')
            app.UIAxes9 = uiaxes(app.GridLayout3);
            title(app.UIAxes9, 'Linear Acceleration')
            xlabel(app.UIAxes9, 'X')
            ylabel(app.UIAxes9, 'Y')
            zlabel(app.UIAxes9, 'Z')
            app.LinearVelocityTab = uitab(app.TabGroup);
            app.LinearVelocityTab.Title = 'Linear Velocity';
            app.GridLayout4 = uigridlayout(app.LinearVelocityTab);
            app.UIAxes10 = uiaxes(app.GridLayout4);
            title(app.UIAxes10, 'Linear Velocity (Filter X)')
            xlabel(app.UIAxes10, 'X')
            ylabel(app.UIAxes10, 'Y')
            zlabel(app.UIAxes10, 'Z')
            app.UIAxes11 = uiaxes(app.GridLayout4);
            title(app.UIAxes11, 'Linear Velocity (Filter O)')
            xlabel(app.UIAxes11, 'X')
            ylabel(app.UIAxes11, 'Y')
            zlabel(app.UIAxes11, 'Z')
            app.PositionTab = uitab(app.TabGroup);
            app.PositionTab.Title = 'Position';
            app.GridLayout5 = uigridlayout(app.PositionTab);
            app.UIAxes12 = uiaxes(app.GridLayout5);
            title(app.UIAxes12, 'Position (Filter X)')
            xlabel(app.UIAxes12, 'X')
            ylabel(app.UIAxes12, 'Y')
            zlabel(app.UIAxes12, 'Z')
            app.UIAxes13 = uiaxes(app.GridLayout5);
            title(app.UIAxes13, 'Position (Filter O)')
            xlabel(app.UIAxes13, 'X')
            ylabel(app.UIAxes13, 'Y')
            zlabel(app.UIAxes13, 'Z')
            app.DAnimationTab = uitab(app.TabGroup);
            app.DAnimationTab.Title = '3D Animation';
            app.GridLayout6 = uigridlayout(app.DAnimationTab);
            app.UIAxes14 = uiaxes(app.GridLayout6);
            title(app.UIAxes14, 'Title')
            xlabel(app.UIAxes14, 'X')
            ylabel(app.UIAxes14, 'Y')
            zlabel(app.UIAxes14, 'Z')
            app.SampleDataEditFieldLabel = uilabel(app.GridLayout7);
            app.SampleDataEditFieldLabel.Text = 'Sample Data';
            app.SampleDataEditField = uieditfield(app.GridLayout7, 'numeric');
            app.SampleDataEditField.ValueChangedFcn = createCallbackFcn(app, @SampleDataEditFieldValueChanged, true);
            app.SampleDataEditField.Value = 500;
            app.GridLayout8 = uigridlayout(app.GridLayout7);
            app.PortEditFieldLabel = uilabel(app.GridLayout8);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.GridLayout8, 'text');
            app.PortEditField.ValueChangedFcn = createCallbackFcn(app, @PortEditFieldValueChanged, true);
            app.PortEditField.Value = 'COM9';
            app.ConnectButton = uibutton(app.GridLayout7, 'push');
            app.ConnectButton.ButtonPushedFcn = createCallbackFcn(app, @ConnectSerial, true);
            app.ConnectButton.Text = 'Connect';
            app.FilterButtonGroup = uibuttongroup(app.GridLayout7);
            app.FilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterButtonGroupSelectionChanged, true);
            app.FilterButtonGroup.Title = 'Filter';
            app.MadgwickButton = uiradiobutton(app.FilterButtonGroup);
            app.MadgwickButton.Text = 'Madgwick';
            app.MadgwickButton.Value = true;
            app.MahonyButton = uiradiobutton(app.FilterButtonGroup);
            app.MahonyButton.Text = 'Mahony';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = evalkit_app_forBLE

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end